classdef HXApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        UpdateControlsPanel            matlab.ui.container.Panel
        SendControlstoTeensyButton     matlab.ui.control.Button
        StartStopSwitch                matlab.ui.control.Switch
        Piezo1ControlPanel             matlab.ui.container.Panel
        Enable1                        matlab.ui.control.Switch
        Phase1                         matlab.ui.control.NumericEditField
        PhasedegEditFieldLabel         matlab.ui.control.Label
        Amplitude1                     matlab.ui.control.NumericEditField
        AmplitudePPVEditFieldLabel     matlab.ui.control.Label
        Frequency1                     matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel      matlab.ui.control.Label
        SwitchBackgroundColor          matlab.ui.control.Image
        Piezo2ControlPanel             matlab.ui.container.Panel
        Phase2                         matlab.ui.control.NumericEditField
        PhasedegEditFieldLabel_2       matlab.ui.control.Label
        Amplitude2                     matlab.ui.control.NumericEditField
        AmplitudePPVEditFieldLabel_2   matlab.ui.control.Label
        Frequency2                     matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel_2    matlab.ui.control.Label
        Enable2                        matlab.ui.control.Switch
        HeaterControlPanel             matlab.ui.container.Panel
        RopeHeaterSwitch               matlab.ui.control.Switch
        RopeHeaterSwitchLabel          matlab.ui.control.Label
        HeaterModulesSwitch            matlab.ui.control.Switch
        HeaterModulesSwitchLabel       matlab.ui.control.Label
        InletFluidTemp                 matlab.ui.control.NumericEditField
        InletFluidTempCEditFieldLabel  matlab.ui.control.Label
        HeatFlux                       matlab.ui.control.NumericEditField
        HeatFluxWcm2EditFieldLabel     matlab.ui.control.Label
        TeensyConnectionSettingsPanel  matlab.ui.container.Panel
        ConnectButton                  matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        DefaultConnectSettings         matlab.ui.control.CheckBox
        BaudrateDropDown               matlab.ui.control.DropDown
        BaudrateDropDownLabel          matlab.ui.control.Label
        COMPortDropDown                matlab.ui.control.DropDown
        COMPortDropDownLabel           matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        boilSurfacePlot                matlab.ui.control.UIAxes
        flowRatePlot                   matlab.ui.control.UIAxes
        outletPressurePlot             matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        AverageHeaterModuleTempCPanel  matlab.ui.container.Panel
        avgHeaterTemp                  matlab.ui.control.Label
        avgHeaterTempGage              matlab.ui.control.Gauge
        DebugWindowPanel               matlab.ui.container.Panel
        DebugTextArea                  matlab.ui.control.TextArea
        InletFluidTempCPanel           matlab.ui.container.Panel
        InletTemp                      matlab.ui.control.Label
        InletTempGage                  matlab.ui.control.Gauge
        Toolbar                        matlab.ui.container.Toolbar
        PIDtune                        matlab.ui.container.toolbar.PushTool
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        iTextBox        % Line in debug textbox
        configuration   % Variables relating to current configuration
        variables       % Misc variables
        teensy          % Teensy object
        debugFile       % Debug file
        plotTimer       % Timer object for updating plots/visuals
        outletPressureVaporLineObject       % Line object for outletPressurePlot vapor
        outletPressureLiquidLineObject      % Line object for outletPressurePlot vapor
        boilSurfaceLineObject1              % Line object for boilSurfacePlot 1
        boilSurfaceLineObject2              % Line object for boilSurfacePlot 2
        boilSurfaceLineObject3              % Line object for boilSurfacePlot 3
        boilSurfaceLineObject4              % Line object for boilSurfacePlot 4
        boilSurfaceLineObjectAvg            % Line object for boilSurfacePlot avg
        flowRateLineObject                  % Line object for flowRatePlot
        
    end
    
    methods (Access = private)
             
        function getSerialPortList(app)
            try
                displayDebug(app, 'Fetching COM settings');
                serialPorts = serialportlist("available");
                list = ["Select"]; %#ok<NBRAK> 
                for i=1:length(serialPorts)
                    list(end+1) = serialPorts(i); %#ok<AGROW> 
                end
                app.configuration.comList       = list;
                displayDebug(app, 'Fetched COM settings');
            catch
                app.configuration.comList = ["COM1", "COM2", "COM3", "COM4", "COM5", "COM6", "COM7", "COM8", "COM9", "COM10", "COM11", "COM12"];
                displayDebug(app, "Error in loading available ports, loaded default list")
            end
            app.configuration.baudList      = ["Select", "1200", "2400", "4800", "9600", "19200", "38400", "57600", "115200"];
            app.BaudrateDropDown.Items      = app.configuration.baudList;
            app.COMPortDropDown.Items       = app.configuration.comList;
            app.BaudrateDropDown.Value      = "Select";
            app.COMPortDropDown.Value       = "Select";
        end
        
        function displayDebug(app, message)
            fullMessage = strjoin({datestr(now,'HH:MM:SS.FFF') ':' message});
            % Display to the dialogue box in the app
            app.iTextBox = app.iTextBox + 1;
            app.DebugTextArea.Value(app.iTextBox) = {fullMessage};
            % Save to debugFile property for final debug file
            app.debugFile.text(app.iTextBox) = {fullMessage};
        end
        
        function initTeensyConnection(app)
            displayDebug(app, 'Starting Teensy connection');
            app.teensy = serialport(app.configuration.comPort, app.configuration.BAUD);
            configureTerminator(app.teensy, "CR/LF");
            configureCallback(app.teensy, "terminator", @app.runDataAquisition)
            flush(app.teensy);
            displayDebug(app, 'Successful connection to Teensy');
            app.COMPortDropDown.Value   = app.configuration.comPort;
            app.BaudrateDropDown.Value  = num2str(app.configuration.BAUD);
            initDataAquisition(app);
        end
        
        function initDataAquisition(app)
            app.teensy.UserData = struct("time",[], ...
                                         "inletPressureUpstream",[], ...
                                         "inletPressureDownstream",[], ...
                                         "outletPressureVapor",[], ...
                                         "outletPressureLiquid",[], ...
                                         "heatFlux",[], ...
                                         "heaterTemperature1",[], ...
                                         "heaterTemperature2",[], ...
                                         "heaterTemperature3",[], ...
                                         "heaterTemperature4",[], ...
                                         "heaterTemperature5",[], ...
                                         "boilSurfaceTemperature1",[], ...
                                         "boilSurfaceTemperature2",[], ...
                                         "boilSurfaceTemperature3",[], ...
                                         "boilSurfaceTemperature4",[], ...
                                         "averageBoilSurfaceTemp",[], ...
                                         "inletFlowRate",[], ...
                                         "inletFluidTemperature",[], ...
                                         "targetFluidTemperature",[], ...
                                         "frequency1",[], ...
                                         "frequency2",[], ...
                                         "amplitude1",[], ...
                                         "amplitude2",[], ...
                                         "phase1",[], ...
                                         "phase2",[], ...
                                         "enable1",[], ...
                                         "enable2",[], ...
                                         "Kp",[], ...
                                         "Kd",[], ...
                                         "Ki",[], ...
                                         "endTesting", []);
        end

        function saveData(app)
            displayDebug(app, strjoin({'Saving data to' app.variables.fileName}));
            s = app.teensy.UserData;
            FID = fopen(app.variables.fileName,'w');    % open file at the filename
            headers = fieldnames(s);                    % Get the fieldnames in struct
            m = length(headers);
            sz = zeros(m,2);
            t = length(s);
            % Modify mx0 to 0xm data
            for q=1:m
               s.(headers{q}) = s.(headers{q})'; 
            end
            
            for rr = 1:t
                l = '';
                for ii = 1:m
                    sz(ii,:) = size(s(rr).(headers{ii}));   
                    if ischar(s(rr).(headers{ii}))
                        sz(ii,2) = 1;
                    end
                    l = [l,'"',headers{ii},'",',repmat(',',1,sz(ii,2)-1)];
                end
                l = [l,'\n'];
                fprintf(FID,l);
                n = max(sz(:,1));
                for ii = 1:n
                    l = '';
                    for jj = 1:m
                        c = s(rr).(headers{jj});
                        str = '';
                        
                        if sz(jj,1)<ii
                            str = repmat(',',1,sz(jj,2));
                        else
                            if isnumeric(c)
                                for kk = 1:sz(jj,2)
                                    str = [str,num2str(c(ii,kk)),','];
                                end
                            elseif islogical(c)
                                for kk = 1:sz(jj,2)
                                    str = [str,num2str(double(c(ii,kk))),','];
                                end
                            elseif ischar(c)
                                str = ['"',c(ii,:),'",'];
                            elseif iscell(c)
                                if isnumeric(c{1,1})
                                    for kk = 1:sz(jj,2)
                                        str = [str,num2str(c{ii,kk}),','];
                                    end
                                elseif islogical(c{1,1})
                                    for kk = 1:sz(jj,2)
                                        str = [str,num2str(double(c{ii,kk})),','];
                                    end
                                elseif ischar(c{1,1})
                                    for kk = 1:sz(jj,2)
                                        str = [str,'"',c{ii,kk},'",'];
                                    end
                                end
                            end
                        end
                        l = [l,str];
                    end
                    l = [l,'\n'];
                    fprintf(FID,l);
                end
                fprintf(FID,'\n');
            end
            fclose(FID);
            initDataAquisition(app);                        % Re-Initiate the data struct (clear the data)
            displayDebug(app, 'Reset data struct');
        end

        function sendDataTeensy(app)
            % Get current values for vars to send
            if (app.Enable1.Value == "On")
                app.variables.enable1 = 1;
            else
                app.variables.enable1 = 0;
            end
            if (app.Enable2.Value == "On")
                app.variables.enable2 = 1;
            else
                app.variables.enable2 = 0;
            end
            app.variables.frequency1        = app.Frequency1.Value;
            app.variables.amplitude1        = app.Amplitude1.Value;
            app.variables.phase1            = app.Phase1.Value;
            app.variables.frequency2        = app.Frequency2.Value;
            app.variables.amplitude2        = app.Amplitude2.Value;
            app.variables.phase2            = app.Phase2.Value;
            app.variables.heatFlux          = app.HeatFlux.Value;
            app.variables.targetFluidTemp   = app.InletFluidTemp.Value;
            if (app.HeaterModulesSwitch.Value == "On")
                app.variables.enableHeaters = 1;
            else
                app.variables.enableHeaters = 0;
            end
            if (app.RopeHeaterSwitch.Value == "On")
                app.variables.enableRopeHeater = 1;
            else
                app.variables.enableRopeHeater = 0;
            end
            % Create list of variables to send to Teensy
            sendList         = [app.variables.enable1, ...
                                app.variables.frequency1, ...
                                app.variables.amplitude1, ...
                                app.variables.phase1, ...
                                app.variables.enable2, ...
                                app.variables.frequency2, ...
                                app.variables.amplitude2, ...
                                app.variables.phase2, ...
                                app.variables.heatFlux, ...
                                app.variables.targetFluidTemp, ...
                                app.variables.enableHeaters, ...
                                app.variables.enableRopeHeater, ...
                                app.variables.Kp, ...
                                app.variables.Kd, ...
                                app.variables.Ki, ...
                                app.variables.endTesting];
            % Construct string to send via serial
            sendString = append(strjoin(string(sendList), app.variables.teensy_delimiter), app.variables.teensy_terminator);
            writeline(app.teensy, sendString);       % Send string via serial
            displayDebug(app, 'Sent data to teensy');
        end

        function runDataAquisition(app,~,~)
            % Check if the Start switch is activated, if so then aquire
            % data
            if (app.StartStopSwitch.Value == "Start" || app.variables.firstData)
                data = str2double(split(readline(app.teensy), ","));
                i = 1;
                app.teensy.UserData.time(end+1)                     = data(i); i=i+1;
                app.teensy.UserData.inletPressureUpstream(end+1)    = data(i); i=i+1;
                app.teensy.UserData.inletPressureDownstream(end+1)  = data(i); i=i+1;
                app.teensy.UserData.outletPressureVapor(end+1)      = data(i); i=i+1;
                app.teensy.UserData.outletPressureLiquid(end+1)     = data(i); i=i+1;
                app.teensy.UserData.heatFlux(end+1)                 = data(i); i=i+1;
                app.teensy.UserData.heaterTemperature1(end+1)       = data(i); i=i+1;
                app.teensy.UserData.heaterTemperature2(end+1)       = data(i); i=i+1;
                app.teensy.UserData.heaterTemperature3(end+1)       = data(i); i=i+1;
                app.teensy.UserData.heaterTemperature4(end+1)       = data(i); i=i+1;
                app.teensy.UserData.heaterTemperature5(end+1)       = data(i); i=i+1;
                app.teensy.UserData.boilSurfaceTemperature1(end+1)  = data(i); i=i+1;
                app.teensy.UserData.boilSurfaceTemperature2(end+1)  = data(i); i=i+1;
                app.teensy.UserData.boilSurfaceTemperature3(end+1)  = data(i); i=i+1;
                app.teensy.UserData.boilSurfaceTemperature4(end+1)  = data(i); i=i+1;
                app.teensy.UserData.averageBoilSurfaceTemp(end+1)   = data(i); i=i+1;
                app.teensy.UserData.inletFlowRate(end+1)            = data(i); i=i+1;
                app.teensy.UserData.inletFluidTemperature(end+1)    = data(i); i=i+1;
                app.teensy.UserData.targetFluidTemperature(end+1)   = data(i); i=i+1;
                app.teensy.UserData.frequency1(end+1)               = data(i); i=i+1;
                app.teensy.UserData.frequency2(end+1)               = data(i); i=i+1;
                app.teensy.UserData.amplitude1(end+1)               = data(i); i=i+1;
                app.teensy.UserData.amplitude2(end+1)               = data(i); i=i+1;
                app.teensy.UserData.phase1(end+1)                   = data(i); i=i+1;
                app.teensy.UserData.phase2(end+1)                   = data(i); i=i+1;
                app.teensy.UserData.enable1(end+1)                  = data(i); i=i+1;
                app.teensy.UserData.enable2(end+1)                  = data(i); i=i+1;
                app.teensy.UserData.Kp(end+1)                       = data(i); i=i+1;
                app.teensy.UserData.Kd(end+1)                       = data(i); i=i+1;
                app.teensy.UserData.Ki(end+1)                       = data(i);
                app.teensy.UserData.endTesting(end+1)               = app.variables.endTesting;
                
                t = app.teensy.UserData.time(end) - app.teensy.UserData.time(1);
                % Add points to pressure plot
                addpoints(app.outletPressureLiquidLineObject, t, app.teensy.UserData.outletPressureLiquid(end));
                addpoints(app.outletPressureVaporLineObject, t, app.teensy.UserData.outletPressureVapor(end));
                
                % Add points to flow rate plot
                addpoints(app.flowRateLineObject, t, app.teensy.UserData.inletFlowRate(end));
                
                % Add points to boil surface plot
                addpoints(app.boilSurfaceLineObject1, t, app.teensy.UserData.boilSurfaceTemperature1(end));
                addpoints(app.boilSurfaceLineObject2, t, app.teensy.UserData.boilSurfaceTemperature2(end));
                addpoints(app.boilSurfaceLineObject3, t, app.teensy.UserData.boilSurfaceTemperature3(end));
                addpoints(app.boilSurfaceLineObject4, t, app.teensy.UserData.boilSurfaceTemperature4(end));
                addpoints(app.boilSurfaceLineObjectAvg, t, app.teensy.UserData.averageBoilSurfaceTemp(end));
                
                % Save Kd, Kp, Ki to variables property
                app.variables.Kp = app.teensy.UserData.Kp(end);
                app.variables.Ki = app.teensy.UserData.Ki(end);
                app.variables.Kd = app.teensy.UserData.Kd(end);
                
                % If first data, update values on UI
                if (app.variables.firstData || app.variables.updateData)
                    if (app.teensy.UserData.enable1(end) == 1)
                        app.Enable1.Value               = "On";
                    else
                        app.Enable1.Value               = "Off";
                    end
                    if (app.teensy.UserData.enable2(end) == 1)
                        app.Enable2.Value               = "On";
                    else
                        app.Enable2.Value               = "Off";
                    end
                    if (app.teensy.UserData.heatFlux(end) > 0)
                        app.HeaterModulesSwitch.Value   = "On";
                    else
                        app.HeaterModulesSwitch.Value   = "Off";
                    end
                    if (app.teensy.UserData.targetFluidTemperature(end) > 0)
                        app.RopeHeaterSwitch.Value      = "On";
                    else
                        app.RopeHeaterSwitch.Value      = "Off";
                    end
                    app.Frequency1.Value            = app.teensy.UserData.frequency1(end);
                    app.Amplitude1.Value            = app.teensy.UserData.amplitude1(end);
                    app.Phase1.Value                = app.teensy.UserData.phase1(end);
                    app.Frequency2.Value            = app.teensy.UserData.frequency2(end);
                    app.Amplitude2.Value            = app.teensy.UserData.amplitude2(end);
                    app.Phase2.Value                = app.teensy.UserData.phase2(end);
                    app.HeatFlux.Value              = app.teensy.UserData.heatFlux(end);
                    app.InletFluidTemp.Value        = app.teensy.UserData.targetFluidTemperature(end);
                    app.RopeHeaterSwitch.Value      = "On";
                    app.HeaterModulesSwitch.Value   = "On";
                    app.variables.firstData = false;
                    app.variables.updateData = false;
                end
            end
        end
        
        function initVisuals(app)
            % Clear the plots and points
            try
                clearpoints(app.outletPressureLiquidLineObject);
                clearpoints(app.outletPressureVaporLineObject);
                clearpoints(app.boilSurfaceLineObject1);
                clearpoints(app.boilSurfaceLineObject2);
                clearpoints(app.boilSurfaceLineObject3);
                clearpoints(app.boilSurfaceLineObject4);
                clearpoints(app.boilSurfaceLineObjectAvg);
                clearpoints(app.flowRateLineObject);
                cla(app.outletPressurePlot);
                cla(app.boilSurfacePlot);
                cla(app.flowRatePlot);
            catch
            end
            
            % Outlet pressure plot
            app.outletPressurePlot.XLimMode = 'manual';
            app.outletPressurePlot.YLimMode = 'auto';
            app.outletPressurePlot.XLim     = [0 app.configuration.plotSeconds];
            app.outletPressureVaporLineObject   = animatedline(app.outletPressurePlot,"Color", 'r',"LineStyle","-","DisplayName","Vapor");
            app.outletPressureLiquidLineObject  = animatedline(app.outletPressurePlot,"Color", 'b',"LineStyle","-","DisplayName","Liquid");
            legend(app.outletPressurePlot,'Location', 'northwest', 'Orientation', 'vertical');
            grid(app.outletPressurePlot, 'on');
            hold(app.outletPressurePlot, 'on');
            
            % Boil surface plot
            app.boilSurfacePlot.XLimMode    = 'manual';
            app.boilSurfacePlot.YLimMode    = 'auto';
            app.boilSurfacePlot.XLim        = [0 app.configuration.plotSeconds];
            app.boilSurfaceLineObject1      = animatedline(app.boilSurfacePlot,"Color", 'r',"LineStyle","--", "DisplayName","Thermistor 1");
            app.boilSurfaceLineObject2      = animatedline(app.boilSurfacePlot,"Color", 'b',"LineStyle","--", "DisplayName","Thermistor 2");
            app.boilSurfaceLineObject3      = animatedline(app.boilSurfacePlot,"Color", 'g',"LineStyle","--", "DisplayName","Thermistor 3");
            app.boilSurfaceLineObject4      = animatedline(app.boilSurfacePlot,"Color", 'y',"LineStyle","--", "DisplayName","Thermistor 4");
            app.boilSurfaceLineObjectAvg    = animatedline(app.boilSurfacePlot,"Color", 'k',"LineStyle","-","Linewidth",2.0, "DisplayName","Average");
            legend(app.boilSurfacePlot,'Location', 'northwest', 'Orientation', 'vertical');
            grid(app.boilSurfacePlot, 'on');
            hold(app.boilSurfacePlot, 'on');
            
            % Flow rate plot
            app.flowRatePlot.XLimMode       = 'manual';
            app.flowRatePlot.YLimMode       = 'auto';
            app.flowRatePlot.XLim           = [0 app.configuration.plotSeconds];
            app.flowRateLineObject          = animatedline(app.flowRatePlot,"Color", 'k',"LineStyle","-", "DisplayName","Flow Rate");
            legend(app.flowRatePlot,'Location', 'northwest', 'Orientation', 'vertical');
            grid(app.flowRatePlot, 'on');
            hold(app.flowRatePlot, 'on');
            
            % Inlet fluid temp gage and text
            app.InletTempGage.Value         = app.InletTempGage.Limits(1);
            app.InletTemp.Text              = 'NaN';
            
            % Average heater temp gage and text
            app.avgHeaterTempGage.Value     = app.avgHeaterTempGage.Limits(1);
            app.avgHeaterTemp.Text          = 'NaN';
        end
        
        function updateVisuals(app, ~, ~)
            if (length(app.teensy.UserData.time) > 2)
                % Get the correct x limits 
                timeElapsed = app.teensy.UserData.time(end)-app.teensy.UserData.time(1);
                if (timeElapsed < (app.configuration.plotSeconds*0.9))
                    Xmin = 0;
                    Xmax = app.configuration.plotSeconds;
                    app.variables.dTime = app.teensy.UserData.time(end)-app.teensy.UserData.time(end-1);
                    app.variables.numShowData = int8(timeElapsed/app.variables.dTime);
                else
                    Xmin = timeElapsed - app.configuration.plotSeconds;
                    Xmax = Xmin + app.configuration.plotSeconds*1.1;
                end
               
                % Outlet pressure plot
                hold(app.outletPressurePlot, 'on');
                app.outletPressurePlot.XLim = [Xmin Xmax]; 
                hold(app.outletPressurePlot, 'off');
                                
                % Boil surface temp plot
                hold(app.boilSurfacePlot, 'on');
                app.boilSurfacePlot.XLim = [Xmin Xmax];
                hold(app.boilSurfacePlot, 'off');
                
                % Inlet flow rate plot
                hold(app.flowRatePlot, 'on');
                app.flowRatePlot.XLim = [Xmin Xmax];
                hold(app.flowRatePlot, 'off');
                
                % Inlet fluid temp gage and text
                app.InletTempGage.Value         = app.teensy.UserData.inletFluidTemperature(end);
                app.InletTemp.Text              = strjoin({num2str(app.InletTempGage.Value) '(C)'});
                
                % Average heater temp gage and text
                heater1 = app.teensy.UserData.heaterTemperature1(end);
                heater2 = app.teensy.UserData.heaterTemperature2(end);
                heater3 = app.teensy.UserData.heaterTemperature3(end);
                heater4 = app.teensy.UserData.heaterTemperature4(end);
                heater5 = app.teensy.UserData.heaterTemperature5(end);
                avgHeater = (heater1 + heater2 + heater3 + heater4 + heater5)/5;
                app.avgHeaterTempGage.Value     = avgHeater;
                app.avgHeaterTemp.Text          = strjoin({num2str(app.avgHeaterTempGage.Value) '(C)'});
                
                % Draw new points quickly
                drawnow limitrate
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; close all;
            % Filename for log file
            app.debugFile.fileName                      = strjoin({pwd, '/logs/HX1_' datestr(now,'mm-dd-yy__HH_MM_SS') '.txt'}, '');
            app.iTextBox                                = 0;
            app.variables.connected                     = false;
            app.variables.firstData                     = true;
            app.variables.updateData                    = true;
            app.variables.dataJustSent                  = false;
            app.variables.countJustSent                 = 0;
            app.variables.endTesting                    = 1;
            app.variables.teensy_delimiter              = ',';
            app.variables.teensy_terminator             = '?';
            app.variables.lightRedColor                 = [0.95 0.39 0.39];
            app.variables.lightGreenColor               = [0.42 0.85 0.42];
            app.variables.darkRedColor                  = [1 0 0];
            app.variables.darkGreenColor                = [0 1 0];
            app.variables.defaultBackgroundColor        = [0.94 0.94 0.94];
            app.SendControlstoTeensyButton.Enable       = 0;
            
            % Set panel background colors
            app.TeensyConnectionSettingsPanel.BackgroundColor   = app.variables.lightRedColor;
            app.SwitchBackgroundColor.BackgroundColor           = app.variables.lightRedColor;
            
            % Load the last variables saved in the configuration.mat file
            displayDebug(app, 'Loading configuration.mat');
            load configuration.mat BAUD comPort plotSeconds plotUpdateTime readVarCount readVarType sendVarCount sendVarType;
            % Save the variables from configuration.mat in the  
            % configuration property
            app.configuration.comPort           = comPort;
            app.configuration.BAUD              = BAUD;
            app.configuration.readVarCount      = readVarCount;
            app.configuration.sendVarCount      = sendVarCount;
            app.configuration.readVarType       = readVarType;
            app.configuration.sendVarType       = sendVarType;
            app.configuration.plotSeconds       = plotSeconds;        %seconds
            app.configuration.plotUpdateTime    = plotUpdateTime;     %seconds
            displayDebug(app, 'Loaded configuration.mat')
            
            % Load PID values from PIDvalues.mat
            load PIDvalues.mat Ki Kd Kp;
            app.configuration.Kp                = Kp;
            app.configuration.Ki                = Ki;
            app.configuration.Kd                = Kd;
            app.variables.Kp                    = Kp;
            app.variables.Ki                    = Ki;
            app.variables.Kd                    = Kd;
            displayDebug(app, 'Loaded PIDvalues.mat');
            %displayDebug(app, fprintf('Kp: %.2f, Ki: %.2f, Kd %.2f', Kp, Ki, Kd));
            
            % Create timer for plot update
            app.plotTimer = timer;
            app.plotTimer.ExecutionMode     = 'fixedRate';
            app.plotTimer.Period            = plotUpdateTime;
            app.plotTimer.BusyMode          = 'queue';
            app.plotTimer.TimerFcn          = @app.updateVisuals;
            
            % Initialize the visuals
            initVisuals(app);
            
            % Get serial port lists
            getSerialPortList(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop plot timer if running
            try
                if strcmp(app.plotTimer.Running, 'on')
                    stop(app.plotTimer);
                    % Send endTesting to teensy
                    app.variables.endTesting = 1;
                    sendDataTeensy(app);
                    % Save the current test if running
                    displayDebug(app, 'Stopping current test');     % Stop the test
                    saveData(app);                                  % Saves data to file
                end
                delete(app.plotTimer);      % Delete timer object
            catch
                displayDebug(app, 'Error while stopping plot timer');
                delete(app.plotTimer)
            end
                        
            % Save configuration.mat file
            comPort         = app.configuration.comPort;
            BAUD            = app.configuration.BAUD;
            readVarCount    = app.configuration.readVarCount;
            sendVarCount    = app.configuration.sendVarCount;
            readVarType     = app.configuration.readVarType;
            sendVarType     = app.configuration.sendVarType;
            plotSeconds     = app.configuration.plotSeconds;
            plotUpdateTime  = app.configuration.plotUpdateTime; 
            save('configurationTEST.mat', 'BAUD', 'comPort', 'plotSeconds', 'plotUpdateTime', 'readVarCount', ...
            'readVarType', 'sendVarCount', 'sendVarType', '-v7.3');
            displayDebug(app, 'Saved COM values to configuration.mat');
            
            % Save PID values
            Kd              = app.variables.Kd;
            Kp              = app.variables.Kp;
            Ki              = app.variables.Ki;
            save('PIDvalues.mat', 'Kp', 'Ki', 'Kd', '-V7.3');
            displayDebug(app, 'Saved PID values to PIDvalues.mat');
            
            % Save the log file
            logSave = fopen(app.debugFile.fileName, 'w');
            for i=1:length(app.debugFile.text)

                fprintf(logSave,'%s\n',char(app.debugFile.text(i)));
            end
            fclose(logSave);


            % Close app
            delete(app)            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Check if the variables are valid
            comPort = app.COMPortDropDown.Value;
            baudRate = app.BaudrateDropDown.Value;
            goodComPort = false;
            goodBaudRate = false;

            if (any(strcmp(app.configuration.comList, comPort)))
                goodComPort = true;
            end
            if ~(strcmp("Select", baudRate))
                goodBaudRate = true;
                baudRate = str2num(baudRate); %#ok<ST2NM>
            end

            % Send data to the main app if good
            if (goodComPort && goodBaudRate)
                message = strjoin({'using' comPort '@' int2str(baudRate)});
                displayDebug(app, message);
                app.configuration.comPort   = comPort;
                app.configuration.BAUD      = baudRate;
                try
                    initTeensyConnection(app);
                    app.TeensyConnectionSettingsPanel.BackgroundColor = app.variables.lightGreenColor;
                    app.ConnectButton.Enable = 0;           % Disable connect button
                    app.RefreshButton.Enable = 0;           % Disable refresh button
                    app.COMPortDropDown.Enable = 0;         % Disable com port dropdown
                    app.BaudrateDropDown.Enable = 0;        % Disable baudrate dropdown
                    app.DefaultConnectSettings.Enable = 0;  % Disable default connect settings checkbox
                    app.variables.connected = true;
                catch
                    displayDebug(app, 'Fatal error with Teensy init');
                    app.TeensyConnectionSettingsPanel.BackgroundColor = app.variables.lightRedColor;
                end
            else
                displayDebug(app, 'Connection settings illegal');                
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.DefaultConnectSettings.Value = 0;
            getSerialPortList(app);
        end

        % Value changed function: DefaultConnectSettings
        function DefaultConnectSettingsValueChanged(app, event)
            value = app.DefaultConnectSettings.Value;
            if value == 1
                % Check if the default COM port is in current list, if not dispaly error
                comPort = app.configuration.comPort;
                if ~(any(strcmp(app.configuration.comList, comPort)))
                    displayDebug(app, 'Default COM port is not available');
                    app.DefaultConnectSettings.Value = 0;
                else
                    app.BaudrateDropDown.Value    = num2str(app.configuration.BAUD);
                    app.COMPortDropDown.Value     = app.configuration.comPort;
                    displayDebug(app, 'Valid default settings');
                end
            else
                getSerialPortList(app);
            end
        end

        % Value changed function: StartStopSwitch
        function StartStopSwitchValueChanged(app, event)
            value = app.StartStopSwitch.Value;
            if (value == "Start")
                % First check if connected
                if (app.variables.connected)
                    % Create file name for test
                    app.variables.fileName = strjoin({pwd, '/data/HX1_' datestr(now,'mm-dd-yy__HH_MM_SS') '.csv'}, '');
                    % Initiate visuals again
                    initVisuals(app);          
                    % Start the timer for plot update
                    if strcmp(app.plotTimer.Running, 'off')
                        start(app.plotTimer);
                    end
                    % Update endTesting var on teensy
                    app.variables.endTesting = 0;
                    app.variables.Kp = app.configuration.Kp;
                    app.variables.Ki = app.configuration.Ki;
                    app.variables.Kd = app.configuration.Kd;
                    sendDataTeensy(app);
                    % Start the data aquisistion
                    displayDebug(app, 'Starting data logging');
                    app.SwitchBackgroundColor.BackgroundColor = app.variables.lightGreenColor;
            else
                    displayDebug(app, 'Not connected to Teensy yet');
                    app.StartStopSwitch.Value = "Stop & Save";
                end
            else
                app.SwitchBackgroundColor.BackgroundColor = app.variables.lightRedColor;
                % If plot timer running, stop it
                if strcmp(app.plotTimer.Running, 'on')
                    stop(app.plotTimer);
                end
                app.variables.endTesting = 1;
                sendDataTeensy(app);                            % Tell teensy test is ended
                displayDebug(app, 'Stopping current test');     % Stop the test
                saveData(app);                                  % Saves data to file
            end
            
        end

        % Value changed function: HeatFlux
        function HeatFluxValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: InletFluidTemp
        function InletFluidTempValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: HeaterModulesSwitch
        function HeaterModulesSwitchValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: RopeHeaterSwitch
        function RopeHeaterSwitchValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Enable2
        function Enable2ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Frequency2
        function Frequency2ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Amplitude2
        function Amplitude2ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Phase2
        function Phase2ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Frequency1
        function Frequency1ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Amplitude1
        function Amplitude1ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Phase1
        function Phase1ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor;
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Value changed function: Enable1
        function Enable1ValueChanged(app, event)
            app.UpdateControlsPanel.BackgroundColor = app.variables.lightRedColor; 
            app.SendControlstoTeensyButton.Enable = 1;
        end

        % Button pushed function: SendControlstoTeensyButton
        function SendControlstoTeensyButtonPushed(app, event)
            sendDataTeensy(app);
            app.UpdateControlsPanel.BackgroundColor = app.variables.defaultBackgroundColor;
            app.SendControlstoTeensyButton.Enable = 0;
            app.variables.dataJustSent = 1;
        end

        % Clicked callback: PIDtune
        function PIDtuneClicked(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {739, 739, 739};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {739, 739};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {264, '1x', 310};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.TeensyConnectionSettingsPanel = uipanel(app.GridLayout3);
            app.TeensyConnectionSettingsPanel.Title = 'Teensy Connection Settings';
            app.COMPortDropDownLabel = uilabel(app.TeensyConnectionSettingsPanel);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.TeensyConnectionSettingsPanel);
            app.BaudrateDropDownLabel = uilabel(app.TeensyConnectionSettingsPanel);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.TeensyConnectionSettingsPanel);
            app.DefaultConnectSettings = uicheckbox(app.TeensyConnectionSettingsPanel);
            app.DefaultConnectSettings.ValueChangedFcn = createCallbackFcn(app, @DefaultConnectSettingsValueChanged, true);
            app.DefaultConnectSettings.Text = 'Default connection settings';
            app.RefreshButton = uibutton(app.TeensyConnectionSettingsPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ConnectButton = uibutton(app.TeensyConnectionSettingsPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.HeaterControlPanel = uipanel(app.GridLayout3);
            app.HeaterControlPanel.Title = 'Heater Control';
            app.HeatFluxWcm2EditFieldLabel = uilabel(app.HeaterControlPanel);
            app.HeatFluxWcm2EditFieldLabel.Text = 'Heat Flux (W/cm^2)';
            app.HeatFlux = uieditfield(app.HeaterControlPanel, 'numeric');
            app.HeatFlux.ValueChangedFcn = createCallbackFcn(app, @HeatFluxValueChanged, true);
            app.InletFluidTempCEditFieldLabel = uilabel(app.HeaterControlPanel);
            app.InletFluidTempCEditFieldLabel.Text = 'Inlet Fluid Temp (C)';
            app.InletFluidTemp = uieditfield(app.HeaterControlPanel, 'numeric');
            app.InletFluidTemp.ValueChangedFcn = createCallbackFcn(app, @InletFluidTempValueChanged, true);
            app.HeaterModulesSwitchLabel = uilabel(app.HeaterControlPanel);
            app.HeaterModulesSwitchLabel.Text = 'Heater Modules';
            app.HeaterModulesSwitch = uiswitch(app.HeaterControlPanel, 'slider');
            app.HeaterModulesSwitch.ValueChangedFcn = createCallbackFcn(app, @HeaterModulesSwitchValueChanged, true);
            app.RopeHeaterSwitchLabel = uilabel(app.HeaterControlPanel);
            app.RopeHeaterSwitchLabel.Text = 'Rope Heater';
            app.RopeHeaterSwitch = uiswitch(app.HeaterControlPanel, 'slider');
            app.RopeHeaterSwitch.ValueChangedFcn = createCallbackFcn(app, @RopeHeaterSwitchValueChanged, true);
            app.Piezo2ControlPanel = uipanel(app.GridLayout3);
            app.Piezo2ControlPanel.Title = 'Piezo 2 Control';
            app.Enable2 = uiswitch(app.Piezo2ControlPanel, 'slider');
            app.Enable2.ValueChangedFcn = createCallbackFcn(app, @Enable2ValueChanged, true);
            app.FrequencyHzEditFieldLabel_2 = uilabel(app.Piezo2ControlPanel);
            app.FrequencyHzEditFieldLabel_2.Text = 'Frequency (Hz)';
            app.Frequency2 = uieditfield(app.Piezo2ControlPanel, 'numeric');
            app.Frequency2.ValueChangedFcn = createCallbackFcn(app, @Frequency2ValueChanged, true);
            app.AmplitudePPVEditFieldLabel_2 = uilabel(app.Piezo2ControlPanel);
            app.AmplitudePPVEditFieldLabel_2.Text = 'Amplitude (P-P V)';
            app.Amplitude2 = uieditfield(app.Piezo2ControlPanel, 'numeric');
            app.Amplitude2.ValueChangedFcn = createCallbackFcn(app, @Amplitude2ValueChanged, true);
            app.PhasedegEditFieldLabel_2 = uilabel(app.Piezo2ControlPanel);
            app.PhasedegEditFieldLabel_2.Text = 'Phase (deg)';
            app.Phase2 = uieditfield(app.Piezo2ControlPanel, 'numeric');
            app.Phase2.ValueChangedFcn = createCallbackFcn(app, @Phase2ValueChanged, true);
            app.SwitchBackgroundColor = uiimage(app.GridLayout3);
            app.SwitchBackgroundColor.ImageSource = 'background.png';
            app.Piezo1ControlPanel = uipanel(app.GridLayout3);
            app.Piezo1ControlPanel.Title = 'Piezo 1 Control';
            app.FrequencyHzEditFieldLabel = uilabel(app.Piezo1ControlPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.Frequency1 = uieditfield(app.Piezo1ControlPanel, 'numeric');
            app.Frequency1.ValueChangedFcn = createCallbackFcn(app, @Frequency1ValueChanged, true);
            app.AmplitudePPVEditFieldLabel = uilabel(app.Piezo1ControlPanel);
            app.AmplitudePPVEditFieldLabel.Text = 'Amplitude (P-P V)';
            app.Amplitude1 = uieditfield(app.Piezo1ControlPanel, 'numeric');
            app.Amplitude1.ValueChangedFcn = createCallbackFcn(app, @Amplitude1ValueChanged, true);
            app.PhasedegEditFieldLabel = uilabel(app.Piezo1ControlPanel);
            app.PhasedegEditFieldLabel.Text = 'Phase (deg)';
            app.Phase1 = uieditfield(app.Piezo1ControlPanel, 'numeric');
            app.Phase1.ValueChangedFcn = createCallbackFcn(app, @Phase1ValueChanged, true);
            app.Enable1 = uiswitch(app.Piezo1ControlPanel, 'slider');
            app.Enable1.ValueChangedFcn = createCallbackFcn(app, @Enable1ValueChanged, true);
            app.StartStopSwitch = uiswitch(app.GridLayout3, 'slider');
            app.StartStopSwitch.Items = {'Stop & Save', 'Start'};
            app.StartStopSwitch.ValueChangedFcn = createCallbackFcn(app, @StartStopSwitchValueChanged, true);
            app.StartStopSwitch.Value = 'Stop & Save';
            app.UpdateControlsPanel = uipanel(app.GridLayout3);
            app.UpdateControlsPanel.Title = 'Update Controls';
            app.SendControlstoTeensyButton = uibutton(app.UpdateControlsPanel, 'push');
            app.SendControlstoTeensyButton.ButtonPushedFcn = createCallbackFcn(app, @SendControlstoTeensyButtonPushed, true);
            app.SendControlstoTeensyButton.Text = {'Send Controls to Teensy'; ''};
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.outletPressurePlot = uiaxes(app.GridLayout2);
            title(app.outletPressurePlot, 'Outlet Pressure')
            xlabel(app.outletPressurePlot, 'Time (s)')
            ylabel(app.outletPressurePlot, 'Pressure (psia)')
            app.flowRatePlot = uiaxes(app.GridLayout2);
            title(app.flowRatePlot, 'Flow Rate')
            xlabel(app.flowRatePlot, 'Time (s)')
            ylabel(app.flowRatePlot, 'Flow Rate (mL/min)')
            zlabel(app.flowRatePlot, 'Z')
            app.boilSurfacePlot = uiaxes(app.GridLayout2);
            title(app.boilSurfacePlot, 'Boil Surface Temperature')
            xlabel(app.boilSurfacePlot, 'Time (s)')
            ylabel(app.boilSurfacePlot, 'Temperature (C)')
            zlabel(app.boilSurfacePlot, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.InletFluidTempCPanel = uipanel(app.GridLayout4);
            app.InletFluidTempCPanel.Title = 'Inlet Fluid Temp (C)';
            app.InletTempGage = uigauge(app.InletFluidTempCPanel, 'circular');
            app.InletTempGage.Limits = [30 70];
            app.InletTemp = uilabel(app.InletFluidTempCPanel);
            app.InletTemp.Text = {'TEMP (C)'; ''};
            app.DebugWindowPanel = uipanel(app.GridLayout4);
            app.DebugWindowPanel.Title = 'Debug Window';
            app.DebugTextArea = uitextarea(app.DebugWindowPanel);
            app.DebugTextArea.Editable = 'off';
            app.AverageHeaterModuleTempCPanel = uipanel(app.GridLayout4);
            app.AverageHeaterModuleTempCPanel.Title = 'Average Heater Module Temp (C)';
            app.avgHeaterTempGage = uigauge(app.AverageHeaterModuleTempCPanel, 'circular');
            app.avgHeaterTempGage.Limits = [20 230];
            app.avgHeaterTemp = uilabel(app.AverageHeaterModuleTempCPanel);
            app.avgHeaterTemp.Text = {'TEMP (C)'; ''};
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PIDtune = uipushtool(app.Toolbar);
            app.PIDtune.ClickedCallback = createCallbackFcn(app, @PIDtuneClicked, true);
            app.PIDtune.Icon = 'settingsIcon.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HXApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end